classdef spike_sorting < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        GridLayout              matlab.ui.container.GridLayout
        LeftPanel               matlab.ui.container.Panel
        DataPathEditFieldLabel  matlab.ui.control.Label
        DataPathEditField       matlab.ui.control.EditField
        LoadRunButton           matlab.ui.control.Button
        SelectClusterButton     matlab.ui.control.Button
        AddtoClusterxButton     matlab.ui.control.Button
        FunctionListBoxLabel    matlab.ui.control.Label
        FunctionListBox         matlab.ui.control.ListBox
        RunFunctionButton       matlab.ui.control.Button
        DeleteClusterxButton    matlab.ui.control.Button
        ClusterxListBoxLabel    matlab.ui.control.Label
        ClusterxListBox         matlab.ui.control.ListBox
        AddnewClusterButton     matlab.ui.control.Button
        ChannelDropDownLabel    matlab.ui.control.Label
        ChannelDropDown         matlab.ui.control.DropDown
        RightPanel              matlab.ui.container.Panel
        UIAxes                  matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    methods (Access = public)

        %把所有的spikes都画出来
        function waveformsPlotting(app,option)
            global data;
            global c;
            global colors;
            global n;
            global condition;
            global selectedClusters;
            
            spikes_length = size(data.waveforms,2);
            spikes_max = max(data.waveforms,[],'all');
            spikes_min = min(data.waveforms,[],'all');
            t = (0.1:0.1:spikes_length/10)';
            
            switch option
                case 'Show all Spikes'
                    cla(app.UIAxes);
                    plot(app.UIAxes,t,data.waveforms','color','#0072BD');
                    title(app.UIAxes,{'waveforms number:' size(data.waveforms,1)});
                    axis(app.UIAxes,[0 spikes_length/10 spikes_min spikes_max]);
                case 'Show x'
                    app.UIAxes.cla;

                    number = size(app.ClusterxListBox.Value,2);
                    hold(app.UIAxes,"on");
                    selectedClusters = zeros(number,1);
                    condition = 'Show x';
                    c_size = 0;
                    index = [];
                    legendIndex = zeros(1,number) ;
                    legendName = strings(1,number);
                    legend(app.UIAxes,'AutoUpdate','off');
                    for i = 1:number
                        name = split(app.ClusterxListBox.Value(i),' ');
                        switch name{2}
                            case 'Spikes'
                                m = 0;
                            otherwise
                                m = str2num(name{2});
                        end

                        selectedClusters(i) = m;
                        index2add = find(data.idx == m);
                        if size(index2add,1) == 0
                            continue;
                        end
                        index = [index;index2add];

                        legendName(i) = ['Cluster' num2str(m)];
                        legendIndex(i) = index2add(1);

                        c_size = c_size + size(index2add,1);
                        if m
                            if m > size(colors,1)
                                colors = distinguishable_colors(m,'w');
                            end
                            c(index2add) = plot(app.UIAxes,t,data.waveforms(index2add,:)','color',colors(m,:));
                        else
                            c(index2add) = plot(app.UIAxes,t,data.waveforms(index2add,:)','color','#0072BD');
                        end
                    end

                    try
                    legend(app.UIAxes,c(legendIndex),legendName);
                    catch
                    legend(app.UIAxes,'off');
                    end

                    title(app.UIAxes,{'waveforms number:' c_size});
                    hold (app.UIAxes, "off");
                    spikes_max = max(data.waveforms(index,:),[],'all');
                    spikes_min = min(data.waveforms(index,:),[],'all');
                    axis(app.UIAxes,[0 spikes_length/10 spikes_min spikes_max]);
                case 'Show All Clusters'
                    cla(app.UIAxes);
                    colors = distinguishable_colors(n,'w');
                    hold(app.UIAxes,"on");
                    for i = 1:n
                        index = find(data.idx == i);
                        %{
                        if ~size(index,1)
                            data.idx(data.idx>i) = data.idx(data.idx>i)-1;  
                            app.ClusterxListBox.Items(:,end) = [];
                            n = n-1;
                            colors = distinguishable_colors(n,'w');
                            index = find(data.idx == i);
                        end
                        %}
                        legend(app.UIAxes,'AutoUpdate','on');
                        plot(app.UIAxes,t,data.waveforms(index(1),:)','color',colors(i,:),'DisplayName',['Cluster ' int2str(i)]);
                        if size(index,1)>1
                            legend(app.UIAxes,'AutoUpdate','off');
                            c = plot(app.UIAxes,t,data.waveforms(index(2:end),:)','color',colors(i,:));
                        end
                    end
                    number = length(~data.idx);
                    title(app.UIAxes,{'waveforms number:' number}); 
                    axis(app.UIAxes,[0 spikes_length/10 spikes_min spikes_max]);
                    hold(app.UIAxes,"off");

                case 'showUnsortedSpikes'
                    condition = 'Unsorted Spikes';
                    cla(app.UIAxes);
                    rest = find(~data.idx);
                    c(rest) = plot(app.UIAxes,t,data.waveforms(rest,:)','Color','#0072BD');
                    spikes_length = size(data.waveforms,2);
                    spikes_max = max(data.waveforms(rest,:),[],'all');
                    spikes_min = min(data.waveforms(rest,:),[],'all');  
                    k=length(rest);
                    title(app.UIAxes,{'waveforms number:' k});
                    axis(app.UIAxes,[0 spikes_length/10 spikes_min spikes_max]); 
                    
            end

        end

        %{
        %spikes重合的点不再重复画
        function waveformsPlotting(app)
            global data;
            spikes_length = size(data.waveforms,2);
            spikes_max = max(data.waveforms,[],'all');
            spikes_min = min(data.waveforms,[],'all');            
            for i = 1:spikes_length
                a = unique(data.waveforms(:,i));
                scatter(app.UIAxes,i*ones(size(a,1),1),a,".",'MarkerEdgeColor','#0072BD');
                hold on;
                axis([0 spikes_length spikes_min spikes_max]);
            end
            
        end
        
        %}
    end
    
    methods (Access = private)
        
        function addClusters(app,option)
            global n;
            global data;
            global rectangle;
            global c;
            global condition;
            global selectedClusters;
            global colors;
            
            spikes_length = size(data.waveforms,2);
            index = [];
            
            t1=round(rectangle.Position(1),1)*10;
            t2=round(rectangle.Position(1)+rectangle.Position(3),1)*10;
            v1=rectangle.Position(2)*ones(1,t2-t1+1);
            v2=(rectangle.Position(2)+rectangle.Position(4))*ones(1,t2-t1+1);
            
            switch option
                case 'add to cluster x'
                name = split(app.ClusterxListBox.Value,' ');
                switch name{2}
                    case 'Spikes'
                        cluster2add = 0;
                    otherwise
                        cluster2add = str2num(name{2});
                end
            end
            
            switch condition
                case 'Show All Clusters'
                    index = find(data.idx ~= 0);
                case 'Show x'
                    for i = 1:size(selectedClusters,1)
                        index = [index;find(data.idx == selectedClusters(i))];
                    end
                case 'Unsorted Spikes'
                    index = find(data.idx == 0);
            end
            
            for i = 1:size(index,1)
                if sum(data.waveforms(index(i),t1:t2) >= v1 & data.waveforms(index(i),t1:t2) <= v2) >= 1
                    delete(c(index(i)));
                    switch option
                        case 'add to new cluster'
                            data.idx(index(i)) = n;
                        case 'add to cluster x'
                            data.idx(index(i)) = cluster2add;
                    end
                end
            end
            
            switch option
                case 'add to new cluster'
                    computeAverage(app,n);
                case 'add to cluster x'
                    computeAverage(app,cluster2add);
            end
            
            %是否某个cluster空了
            switch condition
                case 'Show x'
                    for i = 1:size(selectedClusters,1)
                        if isempty(find(data.idx == selectedClusters(i), 1))

                            data.idx(data.idx>selectedClusters(i)) = data.idx(data.idx>selectedClusters(i))-1;
                            app.ClusterxListBox.Items(:,end) = [];
                            n = n-1;
                            colors = distinguishable_colors(n,'w');
                            
                            %删除平均值图像对应的曲线
                            deleteAverage(app,selectedClusters(i));
                        else
                            computeAverage(app,selectedClusters(i));
                        end
                    end
            end
            
            index = [];
            for i = 1:size(selectedClusters,1)
                index2add = find(data.idx == selectedClusters(i));
                index = [index;index2add];
            end        
            if size(index,1) 
            spikes_max = max(data.waveforms(index,:),[],'all');
            spikes_min = min(data.waveforms(index,:),[],'all');

            axis(app.UIAxes,[0 spikes_length/10 spikes_min spikes_max]); 
            end

            delete(rectangle);
            title(app.UIAxes,{'waveforms number:' size(index,1)}); 
           
        end
        
    end
    
    methods (Access = private)
        function deleteAverage(app,option)
            global n;
            global averageWaveforms;
            
            for i = option:n
                averageWaveforms(i) = averageWaveforms(i+1);
            end
            averageWaveforms(n+1,:) = [];
            
            drawAverage(app);
        end
        
        function computeAverage(app,option)
            %计算平均值
            global n;
            global averageWaveforms;
            global data;
            if ~option
                for i = 1:n
                    index = find(data.idx == i);
                    averageWaveforms(i,:) = sum(data.waveforms(index,:))/length(index);
                end
            else
                index = find(data.idx == option);
                averageWaveforms(option,:) = sum(data.waveforms(index,:))/length(index);
            end
            
            drawAverage(app);
        end
    end
    
    
    methods (Access = public)
        function drawAverage(app)
            global n
            global f
            global colors
            global averageWaveforms
            
            spikes_length = size(averageWaveforms,2);
            t = (0.1:0.1:spikes_length/10)';
            figure(f);
            cla(f);
            hold on;
            legend('AutoUpdate','on');
            for i = 1:n
                plot(t,averageWaveforms(i,:),'color',colors(i,:),'DisplayName',['Cluster ' int2str(i)]);
            end
            hold off;
            legend('AutoUpdate',"off");
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.ClusterxListBox.Items = {'Unsorted Spikes'};
            addpath(genpath('Functions'))
            addpath(genpath('MyFunctions'))
        end

        % Button pushed function: LoadRunButton
        function LoadRunButtonPushed(app, event)
            global n;
            n = 0;
            global X;
            global data;
            global f;
            global condition;
            
            cla(app.UIAxes);
            condition = "Show All Clusters";
            app.ClusterxListBox.Items = {'Unsorted Spikes'};
            app.ChannelDropDown.Items = {};
            
            if isempty(app.DataPathEditField.Value)
                fig = app.UIFigure;
                message = sprintf('path not found');
                uialert(fig,message,'Warning',...
                'Icon','warning');
            else
                d = uiprogressdlg(app.UIFigure,'Title','Auto Sorting . . .',...
                'Indeterminate','on');
                
                X = spikeSorting(app.DataPathEditField.Value,d);
                
                n = max(data.idx);
                for i = 1:n
                    app.ClusterxListBox.Items{end+1} = ['Cluster ' int2str(i)];
                end
                
                f = figure;
                waveformsPlotting(app,'Show All Clusters');
                
                computeAverage(app,0);
                
                close(d);
            end
        end

        % Button pushed function: SelectClusterButton
        function SelectClusterButtonPushed(app, event)
            global rectangle;
            %{
            global flag;
            
            if  flag == 1
                waveformsPlotting(app,'showUnsortedSpikes');
                flag = 0;
            end 
            %}
            rectangle = drawrectangle(app.UIAxes,'Color','r');
        end

        % Button pushed function: AddtoClusterxButton
        function AddtoClusterxButtonPushed(app, event)
            global rectangle;
            
            %try
                if isvalid(rectangle)
                    addClusters(app,'add to cluster x');
                end
            %catch
            %end
        end

        % Button pushed function: RunFunctionButton
        function RunFunctionButtonPushed(app, event)
            value = app.FunctionListBox.Value;
            global X;
            global data;
                       
            switch value
                case 'Show all Spikes'
                    condition = "Show All spikes";
                    waveformsPlotting(app,'Show all Spikes');
                case 'Show x'
                    waveformsPlotting(app,"Show x");
                case 'Show All Clusters'
                    condition = "Show All Clusters";
                    waveformsPlotting(app,"Show All Clusters");
                case 'Mark x in data'
                    n = size(app.ClusterxListBox.Value,2);
                    option = zeros(n,1);
                    for i = 1:n
                        name = split(app.ClusterxListBox.Value(i),' ');
                        option(i) = str2num(name{2});
                    end
                    Mark_x_all_in_data(option);
                case 'Mark all in data'
                    Mark_x_all_in_data(0);
                case 'View(only) x in data'
                    n = size(app.ClusterxListBox.Value,2);
                    option = zeros(n,1);
                    for i = 1:n
                        name = split(app.ClusterxListBox.Value(i),' ');
                        option(i) = str2num(name{2});
                    end
                     View_only_x_in_data(option);
                case 'x''s frequency'
                    n = size(app.ClusterxListBox.Value,2);
                    Clusters2draw = zeros(n,1);
                    for i = 1:n
                        name = split(app.ClusterxListBox.Value(i),' ');
                        Clusters2draw(i) = str2double(name{2});
                    end
                    frequency_visualization(app.DataPathEditField.Value,data,size(X,1),Clusters2draw);
                case 'all''s frequency'
                    frequency_visualization(app.DataPathEditField.Value,data,size(X,1),0);
            end         
        end

        % Button pushed function: DeleteClusterxButton
        function DeleteClusterxButtonPushed(app, event)
            global data;
            global c;
            global n;
            global condition;
            
            name = split(app.ClusterxListBox.Value,' ');
            m = str2double(name{2});
            
            index = data.idx == m;
            
            data.idx(index) = 0;                
            data.idx(data.idx>m) = data.idx(data.idx>m)-1;  
            app.ClusterxListBox.Items(:,end) = [];
            n = n-1;
            
            switch condition
                case 'Show All Clusters'
                hold(app.UIAxes);
                spikes_length = size(data.waveforms,2);
                t = (0.1:0.1:spikes_length/10)';
                
                c(index) = plot(app.UIAxes,t,data.waveforms(index,:)','Color','#0072BD');
                
                rest = data.idx == 0;
                spikes_max = max(data.waveforms(rest,:),[],'all');
                spikes_min = min(data.waveforms(rest,:),[],'all');  
                k=length(rest);
                title(app.UIAxes,{'waveforms number:' k});
                axis(app.UIAxes,[0 spikes_length/10 spikes_min spikes_max]); 
                
                case 'Show x'
                    waveformsPlotting(app,'showUnsortedSpikes');
            end
            legend(app.UIAxes,'off');
            app.ClusterxListBox.Value = 'Unsorted Spikes';
            deleteAverage(app,m);
        end

        % Button pushed function: AddnewClusterButton
        function AddnewClusterButtonPushed(app, event)
            global n;
            global rectangle;
            global colors;

            if isvalid(rectangle)
                n = n + 1;
                colors = distinguishable_colors(n,'w');
                
                addClusters(app,'add to new cluster');
                app.ClusterxListBox.Items{end+1} = ['Cluster ' int2str(n)];
            end
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {542, 542};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {239, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.DataPathEditFieldLabel = uilabel(app.LeftPanel);
            app.DataPathEditFieldLabel.Text = 'Data Path';
            app.DataPathEditField = uieditfield(app.LeftPanel, 'text');
            app.LoadRunButton = uibutton(app.LeftPanel, 'push');
            app.LoadRunButton.ButtonPushedFcn = createCallbackFcn(app, @LoadRunButtonPushed, true);
            app.LoadRunButton.Text = 'Load&Run';
            app.SelectClusterButton = uibutton(app.LeftPanel, 'push');
            app.SelectClusterButton.ButtonPushedFcn = createCallbackFcn(app, @SelectClusterButtonPushed, true);
            app.SelectClusterButton.Text = 'Select Cluster';
            app.AddtoClusterxButton = uibutton(app.LeftPanel, 'push');
            app.AddtoClusterxButton.ButtonPushedFcn = createCallbackFcn(app, @AddtoClusterxButtonPushed, true);
            app.AddtoClusterxButton.Text = 'Add to Cluster x';
            app.FunctionListBoxLabel = uilabel(app.LeftPanel);
            app.FunctionListBoxLabel.Text = 'Function';
            app.FunctionListBox = uilistbox(app.LeftPanel);
            app.FunctionListBox.Items = {'Show all Spikes', 'Show x', 'Show All Clusters', 'Mark x in data', 'Mark all in data', 'View(only) x in data', 'x''s frequency', 'all''s frequency'};
            app.FunctionListBox.Value = 'Show all Spikes';
            app.RunFunctionButton = uibutton(app.LeftPanel, 'push');
            app.RunFunctionButton.ButtonPushedFcn = createCallbackFcn(app, @RunFunctionButtonPushed, true);
            app.RunFunctionButton.Text = 'Run Function';
            app.DeleteClusterxButton = uibutton(app.LeftPanel, 'push');
            app.DeleteClusterxButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteClusterxButtonPushed, true);
            app.DeleteClusterxButton.Text = 'Delete Cluster x';
            app.ClusterxListBoxLabel = uilabel(app.LeftPanel);
            app.ClusterxListBoxLabel.Text = 'Cluster x';
            app.ClusterxListBox = uilistbox(app.LeftPanel);
            app.ClusterxListBox.Items = {'Unsorted Spikes'};
            app.ClusterxListBox.Multiselect = 'on';
            app.ClusterxListBox.Value = {};
            app.AddnewClusterButton = uibutton(app.LeftPanel, 'push');
            app.AddnewClusterButton.ButtonPushedFcn = createCallbackFcn(app, @AddnewClusterButtonPushed, true);
            app.AddnewClusterButton.Text = 'Add new Cluster';
            app.ChannelDropDownLabel = uilabel(app.LeftPanel);
            app.ChannelDropDownLabel.Text = 'Channel';
            app.ChannelDropDown = uidropdown(app.LeftPanel);
            app.ChannelDropDown.Items = {};
            app.ChannelDropDown.Value = {};
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.RightPanel);
            title(app.UIAxes, 'Result')
            xlabel(app.UIAxes, 'Time(ms)')
            ylabel(app.UIAxes, 'Voltage (mV)')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = spike_sorting

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end